classdef Checklist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChecklistUIFigure              matlab.ui.Figure
        NopersonisintherobotcellandthelightcurtainswitchisonCheckBox  matlab.ui.control.CheckBox
        Lamp                           matlab.ui.control.Lamp
        ConveyorguardisclosedCheckBox  matlab.ui.control.CheckBox
        ModeswitchissettoManualCheckBox  matlab.ui.control.CheckBox
        CheckItemFourCheckBox          matlab.ui.control.CheckBox
        CheckItemFiveCheckBox          matlab.ui.control.CheckBox
        CheckItemSixCheckBox           matlab.ui.control.CheckBox
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
        SafetyStartupChecklistLabel    matlab.ui.control.Label
        Knob                           matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        Property % Description
        CheckList = 0; 
        
    end
    
    methods (Access = private)
        function  isDone(app)
            disp(app.CheckList);
            if app.CheckList == 63
                app.Knob.Visible = 'on';
            else
                app.Knob.Visible = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % NopersonisintherobotcellandthelightcurtainswitchisonCheckBox
        function NopersonisintherobotcellandthelightcurtainswitchisonCheckBoxValueChanged(app, event)
            if app.NopersonisintherobotcellandthelightcurtainswitchisonCheckBox.Value
%                 app.Lamp.Enable = 'on';
                app.Lamp.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 1, 'uint8');
            else
%                 app.Lamp.Enable = 'off';
                app.Lamp.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 1, 0);
            end
            
            isDone(app);
        end

        % Value changed function: ConveyorguardisclosedCheckBox
        function ConveyorguardisclosedCheckBoxValueChanged(app, event)
            if app.ConveyorguardisclosedCheckBox.Value
                app.Lamp_2.Color = [0, 1, 0];
%                 app.Lamp_2.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 2, 'uint8');
            else
%                 app.Lamp_2.Enable = 'off';
                app.Lamp_2.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 2, 0);
            end
            
            isDone(app);
        end

        % Value changed function: ModeswitchissettoManualCheckBox
        function ModeswitchissettoManualCheckBoxValueChanged(app, event)
            if app.ModeswitchissettoManualCheckBox.Value
%                 app.Lamp_3.Enable = 'on';
                app.Lamp_3.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 4, 'uint8');
            else
%                 app.Lamp_3.Enable = 'off';
                app.Lamp_3.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 3, 0);
            end    
            
            isDone(app);
        end

        % Value changed function: CheckItemFourCheckBox
        function CheckItemFourCheckBoxValueChanged(app, event)
            if app.CheckItemFourCheckBox.Value
%                 app.Lamp_4.Enable = 'on';
                app.Lamp_4.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 8, 'uint8');
            else
%                 app.Lamp_4.Enable = 'off';
                app.Lamp_4.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 4, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: CheckItemFiveCheckBox
        function CheckItemFiveCheckBoxValueChanged(app, event)
            if app.CheckItemFiveCheckBox.Value
%                 app.Lamp_5.Enable = 'on';
                app.Lamp_5.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 16, 'uint8');
            else
%                 app.Lamp_5.Enable = 'off';
                app.Lamp_5.Color = [1, 0 , 0];
                app.CheckList = bitset(app.CheckList, 5, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: CheckItemSixCheckBox
        function CheckItemSixCheckBoxValueChanged(app, event)
            if app.CheckItemSixCheckBox.Value
%                 app.Lamp_6.Enable = 'on';
                app.Lamp_6.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 32, 'uint8');
            else
%                 app.Lamp_6.Enable = 'off';
                app.Lamp_6.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 6, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if app.Knob.Value == "On"
                Main2
                delete(app.ChecklistUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChecklistUIFigure = uifigure('Visible', 'off');
            app.ChecklistUIFigure.Name = 'Checklist';
            app.NopersonisintherobotcellandthelightcurtainswitchisonCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.NopersonisintherobotcellandthelightcurtainswitchisonCheckBox.ValueChangedFcn = createCallbackFcn(app, @NopersonisintherobotcellandthelightcurtainswitchisonCheckBoxValueChanged, true);
            app.NopersonisintherobotcellandthelightcurtainswitchisonCheckBox.Text = 'No person is in the robot cell and the light curtain switch is on';
            app.Lamp = uilamp(app.ChecklistUIFigure);
            app.ConveyorguardisclosedCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.ConveyorguardisclosedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConveyorguardisclosedCheckBoxValueChanged, true);
            app.ConveyorguardisclosedCheckBox.Text = 'Conveyor guard is closed';
            app.ModeswitchissettoManualCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.ModeswitchissettoManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModeswitchissettoManualCheckBoxValueChanged, true);
            app.ModeswitchissettoManualCheckBox.Text = 'Mode switch is set to Manual';
            app.CheckItemFourCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemFourCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemFourCheckBoxValueChanged, true);
            app.CheckItemFourCheckBox.Text = 'Check Item Four';
            app.CheckItemFiveCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemFiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemFiveCheckBoxValueChanged, true);
            app.CheckItemFiveCheckBox.Text = 'Check Item Five';
            app.CheckItemSixCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemSixCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemSixCheckBoxValueChanged, true);
            app.CheckItemSixCheckBox.Text = 'Check Item Six';
            app.Lamp_2 = uilamp(app.ChecklistUIFigure);
            app.Lamp_3 = uilamp(app.ChecklistUIFigure);
            app.Lamp_4 = uilamp(app.ChecklistUIFigure);
            app.Lamp_5 = uilamp(app.ChecklistUIFigure);
            app.Lamp_6 = uilamp(app.ChecklistUIFigure);
            app.SafetyStartupChecklistLabel = uilabel(app.ChecklistUIFigure);
            app.SafetyStartupChecklistLabel.Text = 'Safety Startup Checklist';
            app.Knob = uiknob(app.ChecklistUIFigure, 'discrete');
            app.Knob.Items = {'Off', 'On'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Visible = 'off';
            % Show the figure after all components are created
            app.ChecklistUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Checklist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChecklistUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChecklistUIFigure)
        end
    end
end