classdef ESTOP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Emergency_STOP   matlab.ui.Figure
        Lamp             matlab.ui.control.Lamp
        ESTOPLabel       matlab.ui.control.Label
        Check1           matlab.ui.control.CheckBox
        Lamp2            matlab.ui.control.Lamp
        Check2           matlab.ui.control.CheckBox
        Check3           matlab.ui.control.CheckBox
        Check4           matlab.ui.control.CheckBox
        Check5           matlab.ui.control.CheckBox
        Lamp3            matlab.ui.control.Lamp
        Lamp4            matlab.ui.control.Lamp
        Lamp5            matlab.ui.control.Lamp
        Lamp6            matlab.ui.control.Lamp
        COMPLETEDButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Timer % Description
        Toggle
        CheckList;
    end
    
    methods (Access = private)
        
        function LightToggle(app, ~, ~)
            if app.Toggle == 1
                app.Lamp.Color = [1 0 0];
                app.Toggle = 0;
            else
%                 app.Lamp.Color = [0.7098 0 0];
                app.Lamp.Color = [1, 1, 0];
                app.Toggle = 1;
            end
        end
        
        function  isDone(app)
            disp(app.CheckList);
            if app.CheckList == 31
                app.COMPLETEDButton.Enable = 'on';
            else
                app.COMPLETEDButton.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(timerfind);
            app.Toggle = 1;
            app.CheckList = 0; 
            
            app.Timer = timer('StartDelay', 1, 'Period', 0.5, 'ExecutionMode', 'fixedRate', 'TasksToExecute', Inf);
            app.Timer.TimerFcn = @(obj,event)app.LightToggle(app);
            start(app.Timer);
        end

        % Value changed function: Check1
        function Check1ValueChanged(app, event)
            value = app.Check1.Value;
            if value 
                app.Lamp2.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 1, 'uint8');
            else
                app.Lamp2.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 1, 0);
            end
            isDone(app);
        end

        % Value changed function: Check2
        function Check2ValueChanged(app, event)
            value = app.Check2.Value;
            if value 
                app.Lamp3.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 2, 'uint8');
            else
                app.Lamp3.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 2, 0);
            end
            isDone(app);
        end

        % Value changed function: Check3
        function Check3ValueChanged(app, event)
            value = app.Check3.Value;
            if value 
                app.Lamp4.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 4, 'uint8');
            else
                app.Lamp4.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 3, 0);
            end
            isDone(app);
        end

        % Value changed function: Check4
        function Check4ValueChanged(app, event)
            value = app.Check4.Value;
            if value 
                app.Lamp5.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 8, 'uint8');
            else
                app.Lamp5.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 4, 0);
            end
            isDone(app);
        end

        % Value changed function: Check5
        function Check5ValueChanged(app, event)
            value = app.Check5.Value;
            if value 
                app.Lamp6.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 16, 'uint8');
            else
                app.Lamp6.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 5, 0);
            end
            isDone(app);
        end

        % Close request function: Emergency_STOP
        function Emergency_STOPCloseRequest(app, event)
            delete(app.Timer);
            delete(app)
            
        end

        % Button pushed function: COMPLETEDButton
        function COMPLETEDButtonPushed(app, event)
            Emergency_STOPCloseRequest(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Emergency_STOP = uifigure('Visible', 'off');
            app.Emergency_STOP.Name = 'ESTOP';
            app.Emergency_STOP.CloseRequestFcn = createCallbackFcn(app, @Emergency_STOPCloseRequest, true);
            app.Lamp = uilamp(app.Emergency_STOP);
            app.ESTOPLabel = uilabel(app.Emergency_STOP);
            app.ESTOPLabel.Text = 'E-STOP';
            app.Check1 = uicheckbox(app.Emergency_STOP);
            app.Check1.ValueChangedFcn = createCallbackFcn(app, @Check1ValueChanged, true);
            app.Check1.Text = {'Check that guards for the robot and conveyor have been installed'; ' properly'};
            app.Lamp2 = uilamp(app.Emergency_STOP);
            app.Check2 = uicheckbox(app.Emergency_STOP);
            app.Check2.ValueChangedFcn = createCallbackFcn(app, @Check2ValueChanged, true);
            app.Check2.Text = 'Restock icing and chocolate blocks';
            app.Check3 = uicheckbox(app.Emergency_STOP);
            app.Check3.ValueChangedFcn = createCallbackFcn(app, @Check3ValueChanged, true);
            app.Check3.Text = 'Make sure that all emergency stop buttons are reset';
            app.Check4 = uicheckbox(app.Emergency_STOP);
            app.Check4.ValueChangedFcn = createCallbackFcn(app, @Check4ValueChanged, true);
            app.Check4.Text = 'Make sure that both the Robot and conveyor are powered';
            app.Check5 = uicheckbox(app.Emergency_STOP);
            app.Check5.ValueChangedFcn = createCallbackFcn(app, @Check5ValueChanged, true);
            app.Check5.Text = {'Check to make sure that all operators and personal are standing outside '; 'and that the interlocked doors are closed properly'};
            app.Lamp3 = uilamp(app.Emergency_STOP);
            app.Lamp4 = uilamp(app.Emergency_STOP);
            app.Lamp5 = uilamp(app.Emergency_STOP);
            app.Lamp6 = uilamp(app.Emergency_STOP);
            app.COMPLETEDButton = uibutton(app.Emergency_STOP, 'push');
            app.COMPLETEDButton.ButtonPushedFcn = createCallbackFcn(app, @COMPLETEDButtonPushed, true);
            app.COMPLETEDButton.Enable = 'off';
            app.COMPLETEDButton.Text = 'COMPLETED';
            % Show the figure after all components are created
            app.Emergency_STOP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESTOP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Emergency_STOP)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Emergency_STOP)
        end
    end
end