classdef Main2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OrderTab                      matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        STARTButton_2                 matlab.ui.control.Button
        InkPrintingLampLabel          matlab.ui.control.Label
        InkPrintingLamp               matlab.ui.control.Lamp
        CakeDecorationLampLabel       matlab.ui.control.Label
        CakeDecorationLamp            matlab.ui.control.Lamp
        TextArea_2                    matlab.ui.control.TextArea
        UIAxes_2                      matlab.ui.control.UIAxes
        ErrorLampLabel                matlab.ui.control.Label
        ErrorLamp                     matlab.ui.control.Lamp
        Gauge                         matlab.ui.control.SemicircularGauge
        DemoTab                       matlab.ui.container.Tab
        TableCamera                   matlab.ui.control.UIAxes
        ConveyorCamera                matlab.ui.control.UIAxes
        Notifications                 matlab.ui.container.Panel
        Controls                      matlab.ui.container.Panel
        POWERButton                   matlab.ui.control.Button
        VacuumLabel                   matlab.ui.control.Label
        ConveyorLabel                 matlab.ui.control.Label
        ConveyorDirectionButtonGroup  matlab.ui.container.ButtonGroup
        TowardsButton                 matlab.ui.control.RadioButton
        AwayButton                    matlab.ui.control.RadioButton
        SOLENOIDButton                matlab.ui.control.Button
        STARTButton                   matlab.ui.control.StateButton
        SolenoidButtonGroup           matlab.ui.container.ButtonGroup
        OffButton                     matlab.ui.control.RadioButton
        OnButton                      matlab.ui.control.RadioButton
        ConsolePanel                  matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        CameraTab                     matlab.ui.container.Tab
        TableCameraPanel              matlab.ui.container.Panel
        TCam                          matlab.ui.control.UIAxes
        ConveyorCameraPanel           matlab.ui.container.Panel
        CCam                          matlab.ui.control.UIAxes
        Status                        matlab.ui.control.Lamp
        Knob                          matlab.ui.control.DiscreteKnob
        ROBOTSTATELabel               matlab.ui.control.Label
        TASKLabel                     matlab.ui.control.Label
        CONNECTIONLabel               matlab.ui.control.Label
        DISCONNECTEDLabel             matlab.ui.control.Label
        IDLELabel                     matlab.ui.control.Label
        WAITINGLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Camera objects
        TableCam;
        TableCamRes;
        TableCamBands;
        TableCamSnap;
        TableCamImg;
        TableCamPrev;
        
        ConvCam;
        ConvCamRes;
        ConvCamBands;
        ConvCamSnap;
        ConvCamImg;
        ConvCamPrev;
        
        % IO states 
        Vacuum = 0; 
        Sol = 0;
        Conv = 0;
        ConvDir = 0;  
        
        % Connection
        Robot_IP = '192.168.125.1';
        Simulate_IP = '127.0.0.1'; 
        PORT = 1025;
        Connected = 0;
        
        ConType;

        ErrorFlag; 
        
        %Timer stuff
        Timer;
        ConveyorTimer;
    end
    
    properties (Access = public)
        % Camera Objects
        SnapTable;
        SnapConv;
        
        % Cake Decorations
        InkCharacters;
        
        % IO Status
        iostatus;
        
        Special;
        
        ErrorMsg;
        
        % IO Connection related stuff
        data;
        Socket;
        Commands = [];
        recieved;
        
        % Console incrimenter
        consolecount = 0;
        
    end
    
    methods (Access = private)
        
        function videos(app)
            if app.ConType == 1
                imshow('testImage.jpg', 'Parent',...
                    app.UIAxes);
                imshow('Conveyor6.jpg', 'Parent',...
                    app.UIAxes_2);
                imshow('testImage.jpg', 'Parent',...
                    app.TableCamera);
                imshow('Conveyor6.jpg', 'Parent',...
                    app.ConveyorCamera);
                
            elseif app.ConType == 0
                % Table Cam
                app.TableCam = videoinput('winvideo', 1, 'MJPG_1600x1200');
                app.TableCamRes = app.TableCam.VideoResolution;
                app.TableCamBands = app.TableCam.NumberOfBands;
                app.TableCamImg = imshow(zeros([app.TableCam.Res(2), app.TableCam.Res(1), app.TableCam.Bands]),...
                    'Parent', app.UIAxes);
                app.TableCamImg = imshow(zeros([app.TableCam.Res(2), app.TableCam.Res(1), app.TableCam.Bands]),...
                    'Parent', app.TableCamera);
                app.TableCamPrev = preview(app.TableCam, app.TableCamImg);
                src = getselectedsource(app.TableCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
                
                % Conveyor Cam
                app.ConvCam = videoinput('winvideo', 2, 'MJPG_1600x1200');
                app.ConvCamRes = app.ConvCam.VideoResolution;
                app.ConvCamBands = app.ConvCam.NumberOfBands;
                app.ConvCamImg = imshow(zeros([app.ConvCamRes(2), app.ConvCamRes(1), app.ConvCamBands]),...
                    'Parent', app.ConveyorCamera);
                app.ConvCamPrev = preview(app.ConvCam, app.ConvCamImg);
                src = getselectedsource(app.ConvCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
            end
        end
        
        function main(app,~,~)
            
            if app.Connected == 0
                app.DISCONNECTEDLabel.Text = 'RECONNECTING...';
                app.TextArea_2.Value{app.consolecount} = 'Attempting to reconnect...';
                app.TextArea.Value{app.consolecount} = 'Attempting to reconnect...';
                app.consolecount = app.consolecount + 1;
                app.DISCONNECTEDLabel.FontColor = [1, 0.41, 0.16];
                [app.Socket, app.ConType] = connect();
                app.Connected = 1;
                checkConnection(app);
            end
            
            
            if app.Connected == 1
                    app.recieved = SendCommand(app);
                    if app.recieved ~= -1
                        app.Commands(1) = [];
                    end
                    app.recieved = ParseMessage(app.recieved);
            end
        end
        
        function checkConnection(app)
            if app.ConType == 0
                app.TextArea_2.Value{app.consolecount} = 'Connected to Robot';
                app.TextArea.Value{app.consolecount} = 'Connected to Robot';
                app.DISCONNECTEDLabel.Text = 'CONNECTED';
                app.DISCONNECTEDLabel.FontColor = [0, 1, 0];
                app.Connected = 1;
            elseif app.ConType == 1
                app.TextArea_2.Value{app.consolecount} = 'Connected to Simulator';
                app.TextArea.Value{app.consolecount} = 'Connected to Simulator';
                app.DISCONNECTEDLabel.Text = 'CONNECTED';
                app.DISCONNECTEDLabel.FontColor = [0, 1, 1];
                app.Connected = 1;
            else
                app.TextArea_2.Value{app.consolecount} = 'Disconnected';
                app.TextArea.Value{app.consolecount} = 'Disconnected';
                app.DISCONNECTEDLabel.Text = 'DISCONNECTED';
                app.DISCONNECTEDLabel.FontColor = [1, 0, 0];
                app.Connected = 0;
            end
            app.consolecount = app.consolecount + 1;
        end
        
        function  GetUpdate(app)
            app.SnapConv = getsnapshot(app.ConvCam);
        end
    end
    
    methods (Access = public)
        
        function UpdateConsole(app, value)
            app.TextArea_2.Value{app.consolecount} = value;
            app.TextArea.Value{app.consolecount} = value;
            app.consolecount = app.consolecount + 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.TextArea_2.Value = 'Connecting...';
            app.TextArea.Value = 'Connecting...';
            app.consolecount = app.consolecount + 1;
            app.DISCONNECTEDLabel.Text = 'CONNECTING...';
            app.DISCONNECTEDLabel.FontColor = [1, 0.41, 0.16];
            pause(1);
            [app.Socket, app.ConType] = connect();
            checkConnection(app);
                  
            videos(app);
            
            delete(timerfind);
            
            app.Timer = timer('StartDelay', 2, 'Period', 0.5, 'ExecutionMode', 'fixedRate', 'TasksToExecute', Inf,...
                'BusyMode', 'drop');
            app.Timer.TimerFcn = @(obj,event)app.main(app);
            
            start(app.Timer);
                        
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            if app.STARTButton.Value
                app.STARTButton.Text = "STOP";
            else
                app.STARTButton.Text = "START";
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            if value == "OFF"
                app.Status.Color = [1, 0, 0];
                fclose(app.Socket);
                app.Connected = 0;
            elseif value == "PAUSE"
                app.Status.Color = [1, 0.41, 0.16];
            elseif value == "ON"
                app.Status.Color = [0, 1, 0];
            end
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            app.SnapTable = getsnapshot(app.TableCam);
            app.SnapConv = getsnapshot(app.ConvCam);
            app.ConveyorTimer = timer('StartDelay', 0.5, 'Period', 0.5,...
                'ExecutionMode','fixedRate','TasksToExecute',inf,...
                'BusyMode', 'drop');
            app.ConveyorTimer.TimerFcn = @(obj,event)app.GetUpdate(app);
            start(app.ConveyorTimer);
            app.InkCharacters = ImageProcessing_Final(app);
            inkFinal(app);
            Main_Ultimate(app);
            delete(app.ConveyorTimer);
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            if app.Connected == 1
                fclose(app.Socket);
            end
            
            pause(1);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MainUIFigure);
            app.OrderTab = uitab(app.TabGroup);
            app.OrderTab.Title = 'Order';
            app.UIAxes = uiaxes(app.OrderTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 1];
            app.UIAxes.YTickLabel = '';
            app.STARTButton_2 = uibutton(app.OrderTab, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.InkPrintingLampLabel = uilabel(app.OrderTab);
            app.InkPrintingLampLabel.Text = 'Ink Printing';
            app.InkPrintingLamp = uilamp(app.OrderTab);
            app.CakeDecorationLampLabel = uilabel(app.OrderTab);
            app.CakeDecorationLampLabel.Text = 'Cake Decoration';
            app.CakeDecorationLamp = uilamp(app.OrderTab);
            app.TextArea_2 = uitextarea(app.OrderTab);
            app.UIAxes_2 = uiaxes(app.OrderTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [0 1];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [0 1];
            app.UIAxes_2.YTickLabel = '';
            app.ErrorLampLabel = uilabel(app.OrderTab);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.OrderTab);
            app.Gauge = uigauge(app.OrderTab, 'semicircular');
            app.Gauge.MajorTicks = [0 25 50 75 100];
            app.DemoTab = uitab(app.TabGroup);
            app.DemoTab.Title = 'Demo';
            app.TableCamera = uiaxes(app.DemoTab);
            title(app.TableCamera, '')
            xlabel(app.TableCamera, '')
            ylabel(app.TableCamera, {''; ''})
            app.TableCamera.XTick = [0 1];
            app.TableCamera.XTickLabel = '';
            app.TableCamera.YTick = [0 1];
            app.TableCamera.YTickLabel = '';
            app.ConveyorCamera = uiaxes(app.DemoTab);
            title(app.ConveyorCamera, '')
            xlabel(app.ConveyorCamera, '')
            ylabel(app.ConveyorCamera, '')
            app.ConveyorCamera.XTick = [0 1];
            app.ConveyorCamera.XTickLabel = '';
            app.ConveyorCamera.YTick = [0 1];
            app.ConveyorCamera.YTickLabel = '';
            app.Notifications = uipanel(app.DemoTab);
            app.Notifications.Title = 'Notifications';
            app.Controls = uipanel(app.DemoTab);
            app.Controls.Title = 'Controls';
            app.POWERButton = uibutton(app.Controls, 'push');
            app.POWERButton.Text = 'POWER';
            app.VacuumLabel = uilabel(app.Controls);
            app.VacuumLabel.Text = 'Vacuum';
            app.ConveyorLabel = uilabel(app.Controls);
            app.ConveyorLabel.Text = 'Conveyor';
            app.ConveyorDirectionButtonGroup = uibuttongroup(app.Controls);
            app.ConveyorDirectionButtonGroup.Title = 'Conveyor Direction';
            app.TowardsButton = uiradiobutton(app.ConveyorDirectionButtonGroup);
            app.TowardsButton.Text = 'Towards';
            app.TowardsButton.Value = true;
            app.AwayButton = uiradiobutton(app.ConveyorDirectionButtonGroup);
            app.AwayButton.Text = 'Away';
            app.SOLENOIDButton = uibutton(app.Controls, 'push');
            app.SOLENOIDButton.Text = 'SOLENOID';
            app.STARTButton = uibutton(app.Controls, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.SolenoidButtonGroup = uibuttongroup(app.Controls);
            app.SolenoidButtonGroup.Title = 'Solenoid';
            app.OffButton = uiradiobutton(app.SolenoidButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.OnButton = uiradiobutton(app.SolenoidButtonGroup);
            app.OnButton.Text = {'On'; ''};
            app.ConsolePanel = uipanel(app.DemoTab);
            app.ConsolePanel.Title = 'Console';
            app.TextArea = uitextarea(app.ConsolePanel);
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.TableCameraPanel = uipanel(app.CameraTab);
            app.TableCameraPanel.Title = 'Table Camera';
            app.TCam = uiaxes(app.TableCameraPanel);
            title(app.TCam, '')
            xlabel(app.TCam, '')
            ylabel(app.TCam, '')
            app.TCam.XTick = [0 1];
            app.TCam.XTickLabel = '';
            app.TCam.YTick = [0 1];
            app.TCam.YTickLabel = '';
            app.ConveyorCameraPanel = uipanel(app.CameraTab);
            app.ConveyorCameraPanel.Title = 'Conveyor Camera';
            app.CCam = uiaxes(app.ConveyorCameraPanel);
            title(app.CCam, '')
            xlabel(app.CCam, '')
            ylabel(app.CCam, '')
            app.CCam.XTick = [0 1];
            app.CCam.XTickLabel = '';
            app.CCam.YTick = [0 1];
            app.CCam.YTickLabel = '';
            app.Status = uilamp(app.MainUIFigure);
            app.Knob = uiknob(app.MainUIFigure, 'discrete');
            app.Knob.Items = {'OFF', 'PAUSE', 'ON'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'ON';
            app.ROBOTSTATELabel = uilabel(app.MainUIFigure);
            app.ROBOTSTATELabel.Text = 'ROBOT STATE:';
            app.TASKLabel = uilabel(app.MainUIFigure);
            app.TASKLabel.Text = 'TASK:';
            app.CONNECTIONLabel = uilabel(app.MainUIFigure);
            app.CONNECTIONLabel.Text = 'CONNECTION:';
            app.DISCONNECTEDLabel = uilabel(app.MainUIFigure);
            app.DISCONNECTEDLabel.Text = 'DISCONNECTED';
            app.IDLELabel = uilabel(app.MainUIFigure);
            app.IDLELabel.Text = 'IDLE';
            app.WAITINGLabel = uilabel(app.MainUIFigure);
            app.WAITINGLabel.Text = 'WAITING';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end